classdef e_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TabGroup2               matlab.ui.container.TabGroup
        RunTab                  matlab.ui.container.Tab
        GridLayout7             matlab.ui.container.GridLayout
        DropDown2               matlab.ui.control.DropDown
        TestImageButton         matlab.ui.control.Button
        CleardrawButton         matlab.ui.control.Button
        LoadImageButton         matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        NETLoadedLabel          matlab.ui.control.Label
        LoadNETButton           matlab.ui.control.Button
        Only3bestnetCheckBox    matlab.ui.control.CheckBox
        TestButton              matlab.ui.control.Button
        TrainButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        ResultsTab              matlab.ui.container.Tab
        GridLayout12            matlab.ui.container.GridLayout
        ExportExcelButton       matlab.ui.control.Button
        ExportlogsButton        matlab.ui.control.Button
        Savebest3Button         matlab.ui.control.Button
        Savebest2Button         matlab.ui.control.Button
        Savebest1Button         matlab.ui.control.Button
        plotconfusionButton     matlab.ui.control.Button
        DropDownPlot            matlab.ui.control.DropDown
        TextArea                matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        ParamsTab               matlab.ui.container.Tab
        GridLayout8             matlab.ui.container.GridLayout
        Panel_9                 matlab.ui.container.Panel
        GridLayout10            matlab.ui.container.GridLayout
        Spinner                 matlab.ui.control.Spinner
        CombinaesDropDown       matlab.ui.control.DropDown
        CombinaesDropDownLabel  matlab.ui.control.Label
        Panel_10                matlab.ui.container.Panel
        GridLayout11            matlab.ui.container.GridLayout
        RepetiesLabel           matlab.ui.control.Label
        Spinner_2               matlab.ui.control.Spinner
        Panel_8                 matlab.ui.container.Panel
        GridLayout9             matlab.ui.container.GridLayout
        FunesdetreinoLabel      matlab.ui.control.Label
        fTreino                 matlab.ui.container.CheckBoxTree
        trainlmNode             matlab.ui.container.TreeNode
        trainbfgNode            matlab.ui.container.TreeNode
        trainbrNode             matlab.ui.container.TreeNode
        traincNode              matlab.ui.container.TreeNode
        traincgbNode            matlab.ui.container.TreeNode
        traincgfNode            matlab.ui.container.TreeNode
        traincgpNode            matlab.ui.container.TreeNode
        traingdNode             matlab.ui.container.TreeNode
        traingdaNode            matlab.ui.container.TreeNode
        traingdmNode            matlab.ui.container.TreeNode
        traingdxNode            matlab.ui.container.TreeNode
        trainossNode            matlab.ui.container.TreeNode
        trainrNode              matlab.ui.container.TreeNode
        trainrpNode             matlab.ui.container.TreeNode
        trainsNode              matlab.ui.container.TreeNode
        trainscgNode            matlab.ui.container.TreeNode
        TopologiaLabel          matlab.ui.control.Label
        RatiosLabel             matlab.ui.control.Label
        TopologiaEditField      matlab.ui.control.EditField
        RatiosEditField         matlab.ui.control.EditField
        Panel_7                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        SadaPanel               matlab.ui.container.Panel
        OcultaPanel             matlab.ui.container.Panel
        fSaida                  matlab.ui.container.CheckBoxTree
        purelinNode_2           matlab.ui.container.TreeNode
        competNode_2            matlab.ui.container.TreeNode
        hardlimNode_2           matlab.ui.container.TreeNode
        hardlimsNode_2          matlab.ui.container.TreeNode
        logsigNode_2            matlab.ui.container.TreeNode
        netinvNode_2            matlab.ui.container.TreeNode
        poslinNode_2            matlab.ui.container.TreeNode
        radbasNode_2            matlab.ui.container.TreeNode
        radbasnNode_2           matlab.ui.container.TreeNode
        satlinNode_2            matlab.ui.container.TreeNode
        satlinsNode_2           matlab.ui.container.TreeNode
        softmaxNode_2           matlab.ui.container.TreeNode
        tansigNode_2            matlab.ui.container.TreeNode
        fOculta                 matlab.ui.container.CheckBoxTree
        tansigNode              matlab.ui.container.TreeNode
        competNode              matlab.ui.container.TreeNode
        hardlimNode             matlab.ui.container.TreeNode
        hardlimsNode            matlab.ui.container.TreeNode
        logsigNode              matlab.ui.container.TreeNode
        netinvNode              matlab.ui.container.TreeNode
        poslinNode              matlab.ui.container.TreeNode
        purelinNode             matlab.ui.container.TreeNode
        radbasNode              matlab.ui.container.TreeNode
        radbasnNode             matlab.ui.container.TreeNode
        satlinNode              matlab.ui.container.TreeNode
        satlinsNode             matlab.ui.container.TreeNode
        softmaxNode             matlab.ui.container.TreeNode
        FunesdeativaoLabel      matlab.ui.control.Label
        DatasetTab              matlab.ui.container.Tab
        Panel_4                 matlab.ui.container.Panel
        Label_2                 matlab.ui.control.Label
        Tree                    matlab.ui.container.CheckBoxTree
        AdicionarclasseButton   matlab.ui.control.Button
        RemoverclasseButton     matlab.ui.control.Button
        RemoverpastaButton      matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        AdicionarpastaButton    matlab.ui.control.Button
        Panel_5                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        RootEditField           matlab.ui.control.EditField
        RootEditFieldLabel      matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ConfirmarButton         matlab.ui.control.Button
    end


    properties (Access = private)
        classes = {};
        categories = {};
        rootPath = '';
        net = {};
        bestNET1;
        bestNET2;
        bestNET3;
        trainedNet;
        bestModelIdx;
        allResults;
        step = 1;
        isNetworkLoaded = false;
        isDrawing = false;
        currentImage = ones(224, 224, 'uint8') * 255;
        imageHandle;
        testCases = struct('id', {}, 'networkID', {}, 'actual', {}, 'predicted', {}, 'confMatStr', {});
        currentTestID = 0;
        bestTopology;
        bestHiddenFunc;
        bestOutputFunc;
        bestTrainFunc;
        bestRatios;
    end

    methods (Access = private)

        function [fTreino, fOculta, fSaida] = getCheckedParams(app)

            checkedNodes = app.fTreino.CheckedNodes;

            fTreino = {};

            for i = 1:length(checkedNodes)
                fTreino{i} = checkedNodes(i).NodeData;
            end


            checkedNodes = app.fOculta.CheckedNodes;
            fOculta = {};
            for i = 1:length(checkedNodes)
                fOculta{i} = checkedNodes(i).NodeData;
            end


            checkedNodes = app.fSaida.CheckedNodes;
            fSaida = {};
            for i = 1:length(checkedNodes)
                fSaida{i} = checkedNodes(i).NodeData;
            end
        end

        function [topologias, fOculta, fSaida, fTreino, ratios] = getParams(app)
            topologias = {};
            fOculta = {};
            fSaida = {};
            fTreino = {};
            ratios = [];

            if all(app.RatiosEditField.BackgroundColor == [1 0.8 0.8]) || ...
                    all(app.TopologiaEditField.BackgroundColor == [1 0.8 0.8]) || ...
                    all(app.fTreino.BackgroundColor == [1 0.8 0.8]) || ...
                    all(app.fOculta.BackgroundColor == [1 0.8 0.8]) || ...
                    all(app.fSaida.BackgroundColor == [1 0.8 0.8])
                return;
            end

            [fTreino, fOculta, fSaida] = getCheckedParams(app);
            Topolist = strtrim(app.TopologiaEditField.Value);
            if isempty(Topolist)

                return;
            end
            topologias = strsplit(Topolist, ',');


            for k = 1:numel(topologias)

                topologias{k} = str2num(strtrim(topologias{k}));
            end

            Ratiolist = strtrim(app.RatiosEditField.Value);
            if isempty(Ratiolist)

                return;
            end
            ratios = strsplit(Ratiolist, ',');


            for k = 1:numel(ratios)

                ratios{k} = str2num(strtrim(ratios{k}));
            end
        end

        function combinacoes = gerarCombinacoes(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios)


            switch app.CombinaesDropDown.Value
                case 'Otimizado'
                    combinacoes = gerarCombinacoesOtimizacao(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios);
                case 'Específico'
                    combinacoes = gerarCombinacoesAleatorias(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios, app.Spinner.Value);
                case 'Completo'
                    combinacoes = gerarCombinacoesCompletas(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios);
            end
        end

        function combinacoes = gerarCombinacoesOtimizacao(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios)

            combinacoes = {};

            step = app.step;

            if step == 1
                for t = 1:length(topologias)
                    combinacoes{end+1} = {topologias{t}, funcoesAtivacaoOculta{1}, funcoesAtivacaoSaida{1}, funcoesTreino{1}, ratios{1}};
                end
            elseif step == 2
                for fAtivOculta = 1:length(funcoesAtivacaoOculta)
                    combinacoes{end+1} = {app.bestTopology, funcoesAtivacaoOculta{fAtivOculta}, funcoesAtivacaoSaida{1}, funcoesTreino{1}, ratios{1}};
                end
            elseif step == 3
                for fAtivSaida = 1:length(funcoesAtivacaoSaida)
                    combinacoes{end+1} = {app.bestTopology, app.bestHiddenFunc, funcoesAtivacaoSaida{fAtivSaida}, funcoesTreino{1}, ratios{1}};
                end
            elseif step == 4
                for fTreino = 1:length(funcoesTreino)
                    combinacoes{end+1} = {app.bestTopology, app.bestHiddenFunc, app.bestOutputFunc, funcoesTreino{fTreino}, ratios{1}};
                end
            elseif step == 5
                % Corrected loop and ratio access
                for r = 1:numel(ratios)
                    combinacoes{end+1} = {app.bestTopology, app.bestHiddenFunc, app.bestOutputFunc, app.bestTrainFunc, ratios{r}};
                end
            end
        end

        function combinacoes = gerarCombinacoesCompletas(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios)

            combinacoes = {};

            for t = 1:length(topologias)
                for fAtivOculta = 1:length(funcoesAtivacaoOculta)
                    for fAtivSaida = 1:length(funcoesAtivacaoSaida)
                        for fTreino = 1:length(funcoesTreino)
                            for r = 1:numel(ratios)
                                combinacoes{end+1} = { ...
                                    topologias{t}, ...
                                    funcoesAtivacaoOculta{fAtivOculta}, ...
                                    funcoesAtivacaoSaida{fAtivSaida}, ...
                                    funcoesTreino{fTreino}, ...
                                    ratios{r} ...
                                    };
                            end
                        end
                    end
                end
            end
        end

        function combinacoes = gerarCombinacoesAleatorias(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios, nCombinacoes)

            totalPossiveis = length(topologias) * length(funcoesAtivacaoOculta) * ...
                length(funcoesAtivacaoSaida) * length(funcoesTreino) * size(ratios, 1);

            todasCombinacoes = gerarCombinacoesCompletas(app, topologias, funcoesAtivacaoOculta, funcoesAtivacaoSaida, funcoesTreino, ratios);

            if nCombinacoes >= totalPossiveis

                idx = randperm(totalPossiveis);
                combinacoes = todasCombinacoes(idx);
            else

                idx = randperm(totalPossiveis, nCombinacoes);
                combinacoes = todasCombinacoes(idx);
            end
        end


        function results = updateSpinner(app)
            [topologias, fOculta, fSaida, fTreino, ratios] = getParams(app);
            if isempty(topologias) || isempty(fTreino) || isempty(fSaida) || ...
                    isempty(fOculta) || isempty(ratios)
                app.Spinner.Limits = [0 0];
                app.Spinner.Tooltip = '[0 0]';
            else
                app.Spinner.Limits = [1 length(topologias)*length(fOculta)*length(fSaida)*length(fTreino)*length(ratios)];
                app.Spinner.Value = 1;
                app.Spinner.Tooltip = ['[1 ', num2str(length(topologias)*length(fOculta)*length(fSaida)*length(fTreino)*length(ratios)), ']'];
            end
        end

        function [data, labels, targets] = carregarImagens(app, pastas, classes)

            app.TextArea.Value{end} = ['Carregando imagens... {' strjoin(pastas, ', ') '}'];
            drawnow;
            scroll(app.TextArea, "bottom");
            data = [];
            labels = strings(1, 0);
            imgSize = [28 28];

            if ~iscell(pastas)
                error('pastas deve ser um cell array');
            end
            if ~isstring(classes)
                error('classes deve ser um array de strings');
            end

            for p = 1:length(pastas)
                currentFolder = pastas{p};

                for i = 1:length(classes)
                    classDir = fullfile(app.rootPath,currentFolder, classes(i));
                    arquivos = dir(fullfile(classDir, '*.png'));

                    for k = 1:length(arquivos)

                        imgPath = fullfile(classDir, arquivos(k).name);
                        img = imread(imgPath);
                        img = im2gray(img);
                        img = imbinarize(img);
                        img = imresize(img, imgSize);

                        data(:, end+1) = img(:);
                        labels(end+1) = classes(i);
                    end
                end
            end


            targets = dummyvar(categorical(labels(:)))';
        end

        function updateBestModels(app)

            if isempty(app.allResults)
                app.bestNET1 = [];
                app.bestNET2 = [];
                app.bestNET3 = [];
                return;
            end

            accuracies = [app.allResults.avg_acc];
            [~, sortedIndices] = sort(accuracies, 'descend');


            selectedConfigs = {};
            bestNets = cell(1,3);
            count = 0;

            for k = 1:length(sortedIndices)
                if count >= 3, break; end

                currentIdx = sortedIndices(k);
                currentConfig = app.allResults(currentIdx).config;


                isNew = true;
                for m = 1:length(selectedConfigs)
                    if app.isSameConfig(currentConfig, selectedConfigs{m})
                        isNew = false;
                        break;
                    end
                end

                if isNew
                    count = count + 1;
                    selectedConfigs{end+1} = currentConfig;
                    bestNets{count} = app.allResults(currentIdx).best_net;
                end
            end

            app.TextArea.Value{end+1} = '=== MELHORES REDES ENCONTRADAS ===';
            app.TextArea.Value{end+1} = '';

            for i = 1:3
                currentNet = bestNets{i};
                if ~isempty(currentNet)

                    for j = 1:length(app.allResults)
                        if isequal(currentNet, app.allResults(j).best_net)
                            netConfig = app.allResults(j).config;

                            if iscell(netConfig{5})
                                ratiosStr = mat2str(cell2mat(netConfig{5})); % Converte célula para array
                            else
                                ratiosStr = mat2str(netConfig{5}); % Já é array numérico
                            end
                            app.TextArea.Value{end+1} = sprintf('Melhor Rede #%d:', i);
                            app.TextArea.Value{end+1} = sprintf('  Topologia: %s', mat2str(netConfig{1}));
                            app.TextArea.Value{end+1} = sprintf('  Função Oculta: %s', netConfig{2});
                            app.TextArea.Value{end+1} = sprintf('  Função Saída: %s', netConfig{3});
                            app.TextArea.Value{end+1} = sprintf('  Função Treino: %s', netConfig{4});
                            app.TextArea.Value{end+1} = sprintf('  Ratios: %s', ratiosStr);
                            app.TextArea.Value{end+1} = '';
                            break;
                        end
                    end
                else
                    app.TextArea.Value{end+1} = sprintf('Melhor Rede #%d: Nenhuma rede disponível', i);
                    app.TextArea.Value{end+1} = '';
                end
            end

            app.TextArea.Value{end+1} = '=================================';
            scroll(app.TextArea, "bottom");

            [app.bestNET1, app.bestNET2, app.bestNET3] = deal(bestNets{:});
            if ~app.isNetworkLoaded
                app.Savebest1Button.Enable = ~isempty(app.bestNET1);
                app.Savebest2Button.Enable = ~isempty(app.bestNET2);
                app.Savebest3Button.Enable = ~isempty(app.bestNET3);
            end
        end

        function isSame = isSameConfig(app, config1, config2)

            isSame = isequaln(config1{1}, config2{1}) && ...
                strcmpi(config1{2}, config2{2}) && ...
                strcmpi(config1{3}, config2{3}) && ...
                strcmpi(config1{4}, config2{4}) && ...
                isequaln(config1{5}, config2{5});
        end

        function drawDot(app, x, y)

            x = max(1, min(224, round(x)));
            y = max(1, min(224, round(y)));

            radius = 3;
            for i = max(1, y - radius):min(224, y + radius)
                for j = max(1, x - radius):min(224, x + radius)
                    app.currentImage(i, j) = 0;
                end
            end

            set(app.imageHandle, 'CData', app.currentImage);
            drawnow nocallbacks;
        end
        function eraseDot(app, x, y)
            x = max(1, min(224, round(x)));
            y = max(1, min(224, round(y)));

            radius = 3;
            for i = max(1, y - radius):min(224, y + radius)
                for j = max(1, x - radius):min(224, x + radius)
                    app.currentImage(i, j) = 255;
                end
            end

            set(app.imageHandle, 'CData', app.currentImage);
            drawnow nocallbacks;
        end

        function data = carregarImagem(app)

            img = app.currentImage;


            img = im2gray(img);
            img = imbinarize(img);
            img = imresize(img, [28, 28]);
            data = img(:);

            disp("Imagem desenhada processada para teste.");
        end
        function updateClassesDropdown(app)

            app.DropDown2.Items = app.classes;
        end


        function plotConfusion(app, index)
            if index <= length(app.confusionData)
                actual = app.confusionData(index).actual;
                predicted = app.confusionData(index).predicted;

                actualCategorical = categorical(actual, 1:length(app.classes), app.classes);
                predictedCategorical = categorical(predicted, 1:length(app.classes), app.classes);

                figure;
                plotconfusion(actualCategorical, predictedCategorical);
                title(sprintf('Confusion Matrix (Test Case %d)', index));
            else
                errordlg('Invalid test case index.', 'Error');
            end
        end

        function updateBestModelsTest(app)

            app.bestNET1 = [];
            app.bestNET2 = [];
            app.bestNET3 = [];

            testedNetworks = {};
            for k = 1:length(app.testCases)
                currentID = app.testCases(k).networkID;
                if ~any(strcmp(testedNetworks, currentID))
                    testedNetworks{end+1} = currentID;
                end
            end

            networkPerformance = [];
            for i = 1:length(testedNetworks)
                currentID = testedNetworks{i};
                totalCorrect = 0;
                totalSamples = 0;

                currentNet = [];
                if contains(currentID, 'Best Network')
                    idx = sscanf(currentID, 'Best Network %d');
                    switch idx
                        case 1, currentNet = app.bestNET1;
                        case 2, currentNet = app.bestNET2;
                        case 3, currentNet = app.bestNET3;
                    end
                elseif strcmp(currentID, 'Loaded Network')
                    currentNet = app.trainedNet;
                else
                    idx = str2double(currentID);
                    if ~isnan(idx) && idx <= length(app.allResults)
                        currentNet = app.allResults(idx).best_net;
                    end
                end

                if ~isempty(currentNet)
                    correct = 0;
                    total = 0;
                    for k = 1:length(app.testCases)
                        if strcmp(app.testCases(k).networkID, currentID)
                            correct = correct + sum(app.testCases(k).actual == app.testCases(k).predicted);
                            total = total + length(app.testCases(k).actual);
                        end
                    end
                    networkPerformance(end+1).net = currentNet;
                    networkPerformance(end).accuracy = correct/total;
                end
            end

            for i = 1:length(networkPerformance)-1
                for j = i+1:length(networkPerformance)
                    if networkPerformance(i).accuracy < networkPerformance(j).accuracy
                        temp = networkPerformance(i);
                        networkPerformance(i) = networkPerformance(j);
                        networkPerformance(j) = temp;
                    end
                end
            end

            bestNets = cell(1,3);
            if length(networkPerformance) >= 1, bestNets{1} = networkPerformance(1).net; end
            if length(networkPerformance) >= 2, bestNets{2} = networkPerformance(2).net; end
            if length(networkPerformance) >= 3, bestNets{3} = networkPerformance(3).net; end

            app.bestNET1 = bestNets{1};
            app.bestNET2 = bestNets{2};
            app.bestNET3 = bestNets{3};

            app.Savebest1Button.Enable = ~isempty(app.bestNET1);
            app.Savebest2Button.Enable = ~isempty(app.bestNET2);
            app.Savebest3Button.Enable = ~isempty(app.bestNET3);

            app.TextArea.Value{end+1} = '=== MELHORES REDES ENCONTRADAS ===';
            app.TextArea.Value{end+1} = '';

            for i = 1:3
                currentNet = bestNets{i};
                if ~isempty(currentNet)
                    configFound = false;
                    for j = 1:length(app.allResults)
                        if isequal(currentNet, app.allResults(j).best_net)
                            netConfig = app.allResults(j).config;

                            if iscell(netConfig{5})
                                ratiosStr = mat2str(cell2mat(netConfig{5}));
                            else
                                ratiosStr = mat2str(netConfig{5});
                            end

                            app.TextArea.Value{end+1} = sprintf('Melhor Rede #%d:', i);
                            app.TextArea.Value{end+1} = sprintf('  Topologia: %s', mat2str(netConfig{1}));
                            app.TextArea.Value{end+1} = sprintf('  Função Oculta: %s', netConfig{2});
                            app.TextArea.Value{end+1} = sprintf('  Função Saída: %s', netConfig{3});
                            app.TextArea.Value{end+1} = sprintf('  Função Treino: %s', netConfig{4});
                            app.TextArea.Value{end+1} = sprintf('  Ratios: %s', ratiosStr);
                            app.TextArea.Value{end+1} = '';
                            configFound = true;
                            break;
                        end
                    end
                    if ~configFound
                        app.TextArea.Value{end+1} = sprintf('Melhor Rede #%d: Rede carregada/externa', i);
                        app.TextArea.Value{end+1} = '';
                    end
                else
                    app.TextArea.Value{end+1} = sprintf('Melhor Rede #%d: Nenhuma rede disponível', i);
                    app.TextArea.Value{end+1} = '';
                end
            end

            app.TextArea.Value{end+1} = '=================================';
            scroll(app.TextArea, "bottom");

            if ~app.isNetworkLoaded
                app.Savebest1Button.Enable = ~isempty(app.bestNET1);
                app.Savebest2Button.Enable = ~isempty(app.bestNET2);
                app.Savebest3Button.Enable = ~isempty(app.bestNET3);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fTreino.CheckedNodes = [app.trainlmNode];
            app.fOculta.CheckedNodes = [app.tansigNode];
            app.fSaida.CheckedNodes = [app.purelinNode_2];
            app.Spinner.Value = 1;
            [topologias, fOculta, fSaida, fTreino, ratios] = getParams(app);
            app.Spinner.Tooltip = ['[1 ', num2str(length(topologias)*length(fOculta)*length(fSaida)*length(fTreino)*length(ratios)), ']'];
            app.Spinner.Limits = [1 length(topologias)*length(fOculta)*length(fSaida)*length(fTreino)*length(ratios)];
            app.Label.Text = 'A utilizar pasta atual';
            app.Label.FontColor = [0 0.7 0];

            pastas = {'start', 'train', 'test'};
            for i = 1:length(pastas)
                app.EditField.Value = pastas{i};
                if exist(fullfile(pwd, pastas{i}), 'dir')
                    AdicionarpastaButtonPushed(app);
                end
            end

            % Verificar classes dentro das pastas
            classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            for i = 1:length(classes)
                app.EditField.Value = classes{i};
                found = false;
                for j = 1:length(pastas)
                    if exist(fullfile(pwd, pastas{j}, classes{i}), 'dir')
                        found = true;
                        break;
                    end
                end
                if found
                    AdicionarclasseButtonPushed(app);
                end
            end
            app.EditField.Value = '';
            expand(app.Tree);
            

            app.Savebest1Button.Enable = "off";
            app.Savebest2Button.Enable = "off";
            app.Savebest3Button.Enable = "off";

            app.testCases = struct('id', {}, 'networkID', {}, 'actual', {}, 'predicted', {}, 'confMatStr', {});
            app.currentTestID = 0;
            app.DropDownPlot.Items = {};
            app.TextArea.Value = '';
            app.DropDownPlot.Visible = "off";
            app.DropDownPlot.Enable = "off";
            app.plotconfusionButton.Visible = "off";
            app.plotconfusionButton.Enable = "off";
            app.UIAxes.Position = [48 140 224 224];
            app.UIAxes.XLim = [0.5 224.5];
            app.UIAxes.YLim = [0.5 224.5];
            axis(app.UIAxes, 'image');
            app.imageHandle = imshow(app.currentImage, 'Parent', app.UIAxes);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            hold(app.UIAxes, 'on');
            updateClassesDropdown(app);
            drawnow;
        end

        % Value changed function: TopologiaEditField
        function TopologiaEditFieldValueChanged(app, event)
            updateSpinner(app);

            Topolist = strtrim(app.TopologiaEditField.Value);
            if isempty(Topolist)
                app.TopologiaEditField.BackgroundColor = [1 0.8 0.8];
                return;
            end
            individualTopos = strsplit(Topolist,',');
            for i = 1:length(individualTopos)
                atual = individualTopos{i};
                atual = strtrim(atual);
                camadas = strsplit(atual,' ');

                for l = 1:length(camadas)
                    num = str2double(camadas{l});

                    if isnan(num) || (num ~= floor(num)) || (num <= 0)
                        app.TopologiaEditField.BackgroundColor = [1 0.8 0.8];
                        warndlg(['Valor inválido: "', elemento, '". Use apenas números inteiros positivos.'], 'Erro');
                        return;
                    end
                end
                app.TopologiaEditField.BackgroundColor = [1 1 1];
            end

        end

        % Value changing function: TopologiaEditField
        function TopologiaEditFieldValueChanging(app, event)
            Topolist = strtrim(event.Value);
            if isempty(Topolist)
                app.TopologiaEditField.BackgroundColor = [1 0.8 0.8];
                return;
            end
            individualTopos = strsplit(Topolist,',');
            for i = 1:length(individualTopos)
                atual = individualTopos{i};
                atual = strtrim(atual);
                camadas = strsplit(atual,' ');

                for l = 1:length(camadas)
                    num = str2double(camadas{l});

                    if isnan(num) || (num ~= floor(num)) || (num <= 0)
                        app.TopologiaEditField.BackgroundColor = [1 0.8 0.8];
                        return;
                    end
                end
                app.TopologiaEditField.BackgroundColor = [1 1 1];
            end

        end

        % Value changed function: RatiosEditField
        function RatiosEditFieldValueChanged(app, event)
            updateSpinner(app);

            value = strtrim(app.RatiosEditField.Value);
            if isempty(value)
                app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                return;
            end

            ratios = strsplit(value, ',');

            for i = 1:length(ratios)
                atual = ratios{i};
                atual = strtrim(atual);
                ind = strsplit(atual,' ');
                if length(ind) ~= 3
                    app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                    return;
                end
                isValid = true;
                soma = 0;
                for l = 1:3
                    num = str2double(ind{l});
                    if isnan(num) || num < 0 || num > 1
                        isValid = false;
                    end
                    soma = soma + num;
                end
                if soma ~= 1
                    isValid = false;
                end
                if ~isValid
                    app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                    return;
                end
            end
            app.RatiosEditField.BackgroundColor = [1 1 1];

        end

        % Value changing function: RatiosEditField
        function RatiosEditFieldValueChanging(app, event)
            value = strtrim(event.Value);
            if isempty(value)
                app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                return;
            end

            ratios = strsplit(value, ',');

            for i = 1:length(ratios)
                atual = ratios{i};
                atual = strtrim(atual);
                ind = strsplit(atual,' ');
                if length(ind) ~= 3
                    app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                    return;
                end
                isValid = true;
                soma = 0;
                for l = 1:3
                    num = str2double(ind{l});
                    if isnan(num) || num < 0 || num > 1
                        isValid = false;
                    end
                    soma = soma + num;
                end
                if soma ~= 1
                    isValid = false;
                end
                if ~isValid
                    app.RatiosEditField.BackgroundColor = [1 0.8 0.8];
                    return;
                end
            end
            app.RatiosEditField.BackgroundColor = [1 1 1];

        end

        % Callback function: fSaida
        function fSaidaCheckedNodesChanged(app, event)
            checkedNodes = app.fSaida.CheckedNodes;
            if isempty(checkedNodes)
                app.fSaida.BackgroundColor = [1 0.8 0.8];
            else
                updateSpinner(app);
                app.fSaida.BackgroundColor = [1 1 1];
            end
        end

        % Value changed function: CombinaesDropDown
        function CombinaesDropDownValueChanged(app, event)
            value = app.CombinaesDropDown.Value;
            if strcmp(value, 'Específico')
                app.Spinner.Enable = true;
                app.Spinner.Visible = true;
            else
                app.Spinner.Visible = false;
                app.Spinner.Enable = false;
            end
        end

        % Callback function: fOculta
        function fOcultaCheckedNodesChanged(app, event)
            checkedNodes = app.fOculta.CheckedNodes;
            if isempty(checkedNodes)
                app.fOculta.BackgroundColor = [1 0.8 0.8];
            else
                updateSpinner(app);
                app.fOculta.BackgroundColor = [1 1 1];
            end
        end

        % Callback function: fTreino
        function fTreinoCheckedNodesChanged(app, event)

            checkedNodes = app.fTreino.CheckedNodes;
            if isempty(checkedNodes)
                app.fTreino.BackgroundColor = [1 0.8 0.8];
            else
                updateSpinner(app);
                app.fTreino.BackgroundColor = [1 1 1];
            end
        end

        % Value changed function: RootEditField
        function RootEditFieldValueChanged(app, event)
            value = app.RootEditField.Value;

            if isempty(value)

                value = pwd;
                app.RootEditField.Value = value;
                app.Label.Text = 'A utilizar pasta atual';
                app.Label.FontColor = [0 0.7 0];
            else
                if isfolder(value)
                    app.Label.Text = 'Caminho válido da pasta';
                    app.Label.FontColor = [0 0.7 0];
                else
                    app.Label.Text = 'Caminho inválido - pasta não existe';
                    app.Label.FontColor = [0.8 0 0];
                end
            end
        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonPushed(app, event)
            RootEditFieldValueChanged(app,event);
            if app.Label.FontColor == [0 0.7 0]
                app.Panel_5.Visible = false;
                app.Panel_5.Enable = false;
                app.Panel_4.Visible = true;
                app.Panel_4.Enable = true;
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = strtrim(app.EditField.Value);

            app.Label_2.Text = '';
            app.AdicionarpastaButton.Enable = "off";
            app.AdicionarclasseButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";

            if isempty(value)
                return;
            end

            isCategory = any(strcmpi(value, app.categories));
            isClass = any(strcmpi(value, app.classes));

            app.RemoverpastaButton.Enable = isCategory;
            app.RemoverclasseButton.Enable = isClass;

            isRootFolder = isfolder(fullfile(app.rootPath, value));
            isClassFolder = false;

            if ~isempty(app.categories)

                for i = 1:length(app.categories)
                    if isfolder(fullfile(app.rootPath, app.categories{i}, value))
                        isClassFolder = true;
                        break;
                    end
                end
            end


            if isRootFolder
                app.Label_2.Text = 'Pasta encontrada em pastas';
                app.Label_2.FontColor = [0 0.7 0];
                if ~isCategory
                    app.AdicionarpastaButton.Enable = "on";
                end
            end


            if isClassFolder
                app.Label_2.Text = 'Pasta encontrada em classes';
                app.Label_2.FontColor = [0 0.7 0];
                if ~isClass
                    app.AdicionarclasseButton.Enable = "on";
                end
            end


            if isCategory && isClass
                app.Label_2.Text = 'Nome existe em pastas e classes';
                app.Label_2.FontColor = [0.8 0 0];
            elseif ~isRootFolder && ~isClassFolder
                app.Label_2.Text = 'Nenhuma pasta correspondente encontrada';
                app.Label_2.FontColor = [0.8 0 0];
            end
        end

        % Button pushed function: AdicionarpastaButton
        function AdicionarpastaButtonPushed(app, event)
            value = strtrim(app.EditField.Value);
            if isempty(value)
                return;
            end
            app.EditField.Value = '';
            if any(strcmpi(value, app.categories))
                app.Label_2.Text = 'Pasta já existe em pastas';
                app.Label.FontColor = [0.8 0 0];
            else

                app.categories = [app.categories, value];


                node = uitreenode(app.Tree, 'Text', value, 'NodeData', 'category');
                app.Tree.CheckedNodes = [app.Tree.CheckedNodes; node];


                for i = 1:length(app.classes)
                    className = app.classes{i};

                    classPath = fullfile(app.rootPath, value, className);
                    if ~exist(classPath, 'dir')
                        mkdir(classPath);
                    end

                    uitreenode(node, 'Text', className, 'NodeData', 'class');
                end

                app.Label_2.Text = 'Pasta adicionada a pastas';
                app.Label.FontColor = [0 0.7 0];
                expand(app.Tree);
            end
            app.AdicionarclasseButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";
            app.AdicionarpastaButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";
        end

        % Button pushed function: AdicionarclasseButton
        function AdicionarclasseButtonPushed(app, event)
            value = strtrim(app.EditField.Value);

            if isempty(value)
                return;
            end

            app.EditField.Value = '';

            if any(strcmpi(value, app.classes))
                app.Label_2.Text = 'Pasta já existe em classes';
                app.Label_2.FontColor = [0.8 0 0];
                app.EditField.BackgroundColor = [1 0.8 0.8];
                app.AdicionarclasseButton.Enable = "off";
                app.RemoverclasseButton.Enable = "off";
                app.AdicionarpastaButton.Enable = "off";
                app.RemoverpastaButton.Enable = "off";
                return;
            end

            app.classes = [app.classes, value];
            app.EditField.BackgroundColor = [1 1 1];

            parentCategories = {};
            for i = 1:length(app.categories)
                categoryPath = fullfile(app.rootPath, app.categories{i}, value);
                if isfolder(categoryPath)
                    parentCategories{end+1} = app.categories{i};
                end
            end

            if ~isempty(parentCategories)

                allNodes = app.Tree.Children;

                for i = 1:length(allNodes)

                    if any(strcmp(allNodes(i).Text, parentCategories))

                        node = uitreenode(allNodes(i), 'Text', value, 'NodeData', 'class');

                    end
                end

                app.Label_2.Text = 'Pasta adicionada a classes';
                app.Label_2.FontColor = [0 0.7 0];
                expand(app.Tree);
            else
                app.Label_2.Text = 'Folder not found in any category folders';
                app.Label_2.FontColor = [0.8 0 0];
            end


            app.AdicionarclasseButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";
            app.AdicionarpastaButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";
            updateClassesDropdown(app);
        end

        % Button pushed function: RemoverpastaButton
        function RemoverpastaButtonPushed(app, event)
            value = strtrim(app.EditField.Value);
            if isempty(value)
                return;
            end
            app.EditField.Value = '';

            idx = strcmpi(value, app.categories);

            if any(idx)

                app.categories(idx) = [];


                nodes = app.Tree.Children;
                for i = 1:length(nodes)
                    if strcmpi(nodes(i).Text, value)
                        delete(nodes(i));
                        break;
                    end
                end

                app.Label_2.Text = 'Pasta removida de pastas';
                app.Label.FontColor = [0 0.7 0];
            else
                app.Label_2.Text = 'Pasta não encontrada em pastas';
                app.Label.FontColor = [0.8 0 0];
            end
            app.AdicionarclasseButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";
            app.AdicionarpastaButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";

        end

        % Button pushed function: RemoverclasseButton
        function RemoverclasseButtonPushed(app, event)
            value = strtrim(app.EditField.Value);

            if isempty(value)
                return;
            end

            idx = find(strcmpi(app.classes, value));

            if isempty(idx)
                uialert(app.UIFigure, 'Fould not found in classes', 'Erro');
                return;
            end

            app.classes(idx) = [];


            categoryNodes = app.Tree.Children;
            for catIdx = 1:length(categoryNodes)
                categoryNode = categoryNodes(catIdx);
                classNodes = categoryNode.Children;

                for clsIdx = length(classNodes):-1:1
                    if strcmp(classNodes(clsIdx).Text, value)
                        delete(classNodes(clsIdx));
                    end
                end
            end

            app.EditField.Value = '';
            app.AdicionarclasseButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";
            app.AdicionarpastaButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";
            app.Label_2.Text = sprintf('Pasta removida de classes', value);
            app.Label_2.FontColor = [0 0.7 0];
            updateClassesDropdown(app);

            drawnow;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            value = strtrim(event.Value);

            app.Label_2.Text = '';
            app.AdicionarpastaButton.Enable = "off";
            app.AdicionarclasseButton.Enable = "off";
            app.RemoverpastaButton.Enable = "off";
            app.RemoverclasseButton.Enable = "off";

            if isempty(value)
                return;
            end

            isCategory = any(strcmpi(value, app.categories));
            isClass = any(strcmpi(value, app.classes));

            app.RemoverpastaButton.Enable = isCategory;
            app.RemoverclasseButton.Enable = isClass;

            isRootFolder = isfolder(fullfile(app.rootPath, value));
            isClassFolder = false;

            if ~isempty(app.categories)
                for i = 1:length(app.categories)
                    if isfolder(fullfile(app.rootPath, app.categories{i}, value))
                        isClassFolder = true;
                        break;
                    end
                end
            end

            if isRootFolder
                app.Label_2.Text = 'Pasta encontrada em pastas';
                app.Label_2.FontColor = [0 0.7 0];
                if ~isCategory
                    app.AdicionarpastaButton.Enable = "on";
                end
            end

            if isClassFolder
                app.Label_2.Text = 'Pasta encontrada em classes';
                app.Label_2.FontColor = [0 0.7 0];
                if ~isClass
                    app.AdicionarclasseButton.Enable = "on";
                end
            end

            if isCategory && isClass
                app.Label_2.Text = 'Nome existe em pastas e classes';
                app.Label_2.FontColor = [0.8 0 0];
            elseif ~isRootFolder && ~isClassFolder
                app.Label_2.Text = 'Nenhuma pasta correspondente encontrada';
                app.Label_2.FontColor = [0.8 0 0];
            end
        end

        % Value changing function: RootEditField
        function RootEditFieldValueChanging(app, event)
            value = event.Value;

            if isempty(value)
                value = pwd;
                app.RootEditField.Value = value;
                app.Label.Text = 'A utilizar pasta atual';
                app.Label.FontColor = [0 0.7 0];
            else
                if isfolder(value)
                    app.Label.Text = 'Caminho válido da pasta';
                    app.Label.FontColor = [0 0.7 0];
                else
                    app.Label.Text = 'Caminho inválido - pasta não existe';
                    app.Label.FontColor = [0.8 0 0];
                end
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            prevChecked = event.PreviousCheckedNodes;
            currChecked = app.Tree.CheckedNodes;

            categoryNodes = app.Tree.Children;

            correctedChecked = currChecked;

            removedNodes = setdiff(prevChecked, currChecked);
            addedNodes = setdiff(currChecked, prevChecked);
            changedNodes = [removedNodes; addedNodes];

            for i = 1:numel(changedNodes)
                node = changedNodes(i);
                if ismember(node, categoryNodes)
                    continue;
                end
                parent = node.Parent;
                siblings = parent.Children;

                if numel(siblings) == 1
                    if ismember(node, removedNodes)
                        correctedChecked = setdiff(correctedChecked, parent);
                    else
                        correctedChecked = union(correctedChecked, parent);
                    end
                else
                    if ismember(node, removedNodes)
                        otherChecked = intersect(siblings, correctedChecked);
                        otherChecked = setdiff(otherChecked, node);
                        if isempty(otherChecked)
                            correctedChecked = setdiff(correctedChecked, parent);
                        else
                            correctedChecked = union(correctedChecked, node);
                        end
                    else
                        correctedChecked = union(correctedChecked, parent);
                    end
                end
            end

            if(~isempty(correctedChecked))
                app.Tree.CheckedNodes = unique(correctedChecked);
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Treinar uma nova rede irá limpar qualquer rede que esteja carregada. Continuar?', ...
                'Confirmar', ...
                'Options', {'Sim', 'Não'}, ...
                'DefaultOption', 2);

            if strcmp(choice, 'Não')
                return;
            end

            app.NETLoadedLabel.Enable = "off";
            app.NETLoadedLabel.Visible ="off";

            app.isNetworkLoaded = false;
            app.trainedNet = [];
            app.testCases = struct('id', {}, 'networkID', {}, 'actual', {}, 'predicted', {}, 'confMatStr', {});
            app.DropDownPlot.Visible = "off";
            app.DropDownPlot.Enable = "off";
            app.plotconfusionButton.Visible = "off";
            app.plotconfusionButton.Enable = "off";
            app.currentTestID = 0;
            app.DropDownPlot.Items = {};
            app.TextArea.Value = '';
            app.Savebest1Button.Enable = "off";
            app.Savebest2Button.Enable = "off";
            app.Savebest3Button.Enable = "off";


            app.allResults = [];
            app.bestNET1 = [];
            app.bestNET2 = [];
            app.bestNET3 = [];

            [topologias, fOculta, fSaida, fTreino, ratios] = getParams(app);
            if isempty(topologias) || isempty(fOculta) || isempty(fSaida) || isempty(fTreino) || isempty(ratios)
                errordlg('Parâmetros incompletos!', 'Erro');
                return;
            end

            trainCategories = {};
            for node = app.Tree.CheckedNodes'
                if strcmp(node.NodeData, 'category')
                    trainCategories{end+1} = node.Text;
                end
            end

            if isempty(trainCategories)
                uialert(app.UIFigure, 'Dataset não definido. Selecione as pastas na aba ''Dataset'' primeiro.', ...
                    'Erro de Configuração', ...
                    'Icon', 'error');
                app.TabGroup.SelectedTab = app.DatasetTab;
                return;
            end
            app.TabGroup2.SelectedTab = app.ResultsTab;

            [data, labels, targets] = carregarImagens(app, trainCategories, string(app.classes));

            if strcmp(app.CombinaesDropDown.Value, 'Otimizado')
                app.step = 1;
            else
                app.step = 5;
            end

            combinacoes = {};
            app.allResults = struct('config', {}, 'avg_acc', {}, 'best_net', {}, 'best_tr', {});
            while app.step ~= 6
                novas_combinacoes = gerarCombinacoes(app, topologias, fOculta, fSaida, fTreino, ratios);

                combinacoes = [combinacoes, novas_combinacoes];

                combinacaoIdx = length(combinacoes) - length(novas_combinacoes) + 1;
                if isempty(combinacoes)
                    errordlg('Nenhuma combinação válida gerada.', 'Error');
                    return;
                end
                numReps = app.Spinner_2.Value;


                for combIdx = combinacaoIdx:length(combinacoes)
                    config = combinacoes{combIdx};
                    accuracies = zeros(1, numReps);
                    bestNets = cell(1, numReps);
                    bestTrs = cell(1, numReps);

                    if iscell(config{5})
                        ratiosStr = mat2str(cell2mat(config{5}));
                    else
                        ratiosStr = mat2str(config{5});
                    end
                    if ischar(config{1}) || isstring(config{1})
                        topo = str2num(config{1});
                    else
                        topo = config{1};
                    end
                    if isnumeric(config{1})
                        topoStr = mat2str(config{1});
                    else
                        topoStr = string(config{1});
                    end
                    if strcmp(app.CombinaesDropDown.Value, 'Otimizado')
                        configNum = length(topologias)+length(fOculta)+length(fSaida)+length(fTreino)+length(ratios);
                    else
                        configNum = length(combinacoes);
                    end
                    configMsg = {
                        sprintf('Configuração %d/%d:', combIdx, configNum),
                        sprintf('  Topologia: %s', mat2str(topo)),
                        sprintf('  Função Oculta: %s', config{2}),
                        sprintf('  Função Saída: %s', config{3}),
                        sprintf('  Função Treino: %s', config{4}),
                        sprintf('  Ratios: %s', ratiosStr)
                        };
                    app.TextArea.Value = [app.TextArea.Value; configMsg];
                    drawnow;
                    scroll(app.TextArea, "bottom");


                    for rep = 1:numReps


                        net = feedforwardnet(topo);
                        net.layers{1:end-1}.transferFcn = config{2};
                        net.layers{end}.transferFcn = config{3};
                        net.trainFcn = config{4};
                        if iscell(config{5})
                            ratiosArray = cell2mat(config{5});
                        else
                            ratiosArray = config{5};
                        end
                        net.divideParam.trainRatio = ratiosArray(1);
                        net.divideParam.valRatio = ratiosArray(2);
                        net.divideParam.testRatio = ratiosArray(3);
                        net.trainParam.showWindow = false;
                        net.trainParam.epochs = 100;


                        [net, tr] = train(net, data, targets);


                        if isempty(tr.valInd)
                            evalIndices = tr.trainInd;
                            evalSet = 'treino';
                        else
                            evalIndices = tr.valInd;
                            evalSet = 'validação';
                        end

                        pred = net(data);
                        [~, predicted] = max(pred);
                        [~, actual] = max(targets);
                        accuracy = sum(predicted == actual)/numel(actual);


                        accuracies(rep) = accuracy;
                        bestNets{rep} = net;
                        bestTrs{rep} = tr;


                        progressMsg = sprintf('    Rep %d/%d - Precisão Global: %.2f%%', rep, numReps, accuracy*100);
                        app.TextArea.Value{end+1} = progressMsg;
                        drawnow;
                        scroll(app.TextArea, "bottom");
                    end


                    [bestAcc, bestIdx] = max(accuracies);


                    app.allResults(end+1) = struct(...
                        'config', {config}, ...
                        'avg_acc', mean(accuracies), ...
                        'best_net', bestNets{bestIdx}, ...
                        'best_tr', bestTrs{bestIdx} ...
                        );


                    if(numReps > 1)
                        summaryMsg = {
                            sprintf('  Média: %.2f%%', mean(accuracies)*100),
                            sprintf('  Melhor: %.2f%%', bestAcc*100),
                            '---------------------------------'
                            };
                        app.TextArea.Value = [app.TextArea.Value; summaryMsg];
                    end
                    drawnow;
                    scroll(app.TextArea, "bottom");
                end
                switch app.step
                    case 1
                        app.bestTopology = config{1};
                    case 2
                        app.bestHiddenFunc = config{2};
                    case 3
                        app.bestOutputFunc = config{3};
                    case 4
                        app.bestTrainFunc = config{4};
                    case 5
                        app.bestRatios = config{5};
                end
                app.step = app.step + 1;
            end
            [~, bestIdx] = max([app.allResults.avg_acc]);
            app.trainedNet = app.allResults(bestIdx).best_net;
            updateBestModels(app);


            app.TextArea.Value{end+1} = 'Treinamento concluído!';
            drawnow;
            scroll(app.TextArea, "bottom");
            app.isNetworkLoaded = false;
            app.TrainButton.Enable = 'on';
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value; newline '=== TESTING ==='];


            if isempty(app.allResults) && ~app.isNetworkLoaded
                uialert(app.UIFigure, 'Nenhuma rede treinada disponível. Treine ou carregue uma rede primeiro.', ...
                    'Erro de Teste', 'Icon', 'error');
                return;
            end


            testCategories = {};
            for node = app.Tree.CheckedNodes'
                if strcmp(node.NodeData, 'category')
                    testCategories{end+1} = node.Text;
                end
            end

            if isempty(testCategories)
                uialert(app.UIFigure, 'Dataset não definido. Selecione as pastas na aba ''Dataset'' primeiro.', ...
                    'Erro de Configuração', ...
                    'Icon', 'error');
                app.TabGroup.SelectedTab = app.DatasetTab;
                return;
            end

            app.TabGroup2.SelectedTab = app.ResultsTab;

            [data, labels, targets] = carregarImagens(app, testCategories, string(app.classes));
            [~, actual] = max(targets, [], 1);


            if app.isNetworkLoaded

                networks = {app.trainedNet};
                configs = {[]};
                networkNames = {'Rede Carregada'};
            else
                if app.Only3bestnetCheckBox.Value

                    networks = {app.bestNET1, app.bestNET2, app.bestNET3};
                    configs = cell(1, 3);
                    networkNames = {'Best Network 1', 'Best Network 2', 'Best Network 3'};
                    for i = 1:3
                        configs{i} = [];
                        for j = 1:length(app.allResults)
                            if isequal(networks{i}, app.allResults(j).best_net)
                                configs{i} = app.allResults(j).config;
                                break;
                            end
                        end
                    end
                else
                    networks = cell(1, length(app.allResults));
                    configs = cell(1, length(app.allResults));
                    networkNames = cell(1, length(app.allResults));
                    for i = 1:length(app.allResults)
                        networks{i} = app.allResults(i).best_net;
                        configs{i} = app.allResults(i).config;
                        networkNames{i} = sprintf('%d', i);
                    end
                end
            end


            totalConfigs = length(networks);
            for netIdx = 1:totalConfigs
                net = networks{netIdx};
                config = configs{netIdx};
                if isempty(net)
                    continue;
                end

                if app.isNetworkLoaded
                    networkID = 'Loaded Network';
                elseif app.Only3bestnetCheckBox.Value
                    networkID = sprintf('Best Network %d', netIdx);
                else
                    networkID = sprintf('%d', netIdx);
                end
                if ~isempty(config)

                    topology = mat2str(config{1});
                    hiddenFcn = config{2};
                    outputFcn = config{3};
                    trainFcn = config{4};
                    ratios = mat2str(config{5});

                    result = {
                        sprintf('Configuração %d/%d:', netIdx, totalConfigs)
                        sprintf('  Topologia: %s', topology)
                        sprintf('  Função Oculta: %s', hiddenFcn)
                        sprintf('  Função Saída: %s', outputFcn)
                        sprintf('  Função Treino: %s', trainFcn)
                        sprintf('  Ratios: %s', ratios)
                        };
                else
                    result = {
                        sprintf('Rede importada')
                        };
                end
                app.TextArea.Value = [app.TextArea.Value; result];

                drawnow;
                scroll(app.TextArea, "bottom");

                pred = net(data);
                [~, predicted] = max(pred, [], 1);
                accuracy = sum(predicted == actual)/numel(actual) * 100;
                confMat = confusionmat(actual, predicted);
                classAcc = diag(confMat) ./ sum(confMat, 2) * 100;
                app.currentTestID = app.currentTestID + 1;
                app.DropDownPlot.Items{end+1} = num2str(app.currentTestID);


                if app.isNetworkLoaded
                    networkID = 'Loaded Network';
                elseif app.Only3bestnetCheckBox.Value

                    netIdx = 0;
                    if isequal(net, app.bestNET1)
                        netIdx = 1;
                    elseif ~isempty(app.bestNET2) && isequal(net, app.bestNET2)
                        netIdx = 2;
                    elseif ~isempty(app.bestNET3) && isequal(net, app.bestNET3)
                        netIdx = 3;
                    end
                    networkID = sprintf('Best Network %d', netIdx);
                else

                    networkID = sprintf('%d', netIdx);
                end
                confMatStr = evalc('disp(confMat)');

                app.testCases(end+1) = struct(...
                    'id', app.currentTestID, ...
                    'networkID', networkID, ...
                    'actual', actual, ...
                    'predicted', predicted, ...
                    'confMatStr', confMatStr);

                app.DropDownPlot.Visible = "on";
                app.DropDownPlot.Enable = "on";
                app.plotconfusionButton.Visible = "on";
                app.plotconfusionButton.Enable = "on";

                app.DropDownPlot.Value = app.DropDownPlot.Items{end};
                drawnow;
                scroll(app.TextArea, "bottom");
                classAcc(isnan(classAcc)) = 0;

                precisionResults = cell(length(app.classes), 1);
                for c = 1:length(app.classes)
                    precisionResults{c} = sprintf('    %s: %.2f%%', app.classes{c}, classAcc(c));
                end

                result = [
                    {sprintf('Plot confusion #%d', app.currentTestID)};
                    {sprintf('  Precisão: %.2f%%', accuracy)};
                    {sprintf('  Precisão por classe:')};
                    precisionResults;
                    {''}
                    ];

                app.TextArea.Value = [app.TextArea.Value; result];
                drawnow;
                scroll(app.TextArea, "bottom");
            end

            app.TextArea.Value{end+1} = 'Teste concluído!';
            drawnow;
            scroll(app.TextArea, "bottom");
            updateBestModelsTest(app);
        end

        % Button pushed function: LoadNETButton
        function LoadNETButtonPushed(app, event)
            app.isNetworkLoaded = false;
            app.TrainButton.Enable = 'on';
            [file, path] = uigetfile('*.mat', 'Selecione a rede treinada');
            if file == 0
                return;
            end

            try
                loadedData = load(fullfile(path, file));
                if isfield(loadedData, 'net') && isa(loadedData.net, 'network')
                    app.trainedNet = loadedData.net;
                    app.isNetworkLoaded = true;
                    app.NETLoadedLabel.Enable = "on";
                    app.NETLoadedLabel.Visible ="on";
                    app.NETLoadedLabel.Text = [file ' loaded'];

                    app.allResults = [];
                    app.bestNET1 = [];
                    app.bestNET2 = [];
                    app.bestNET3 = [];
                else
                    error('Arquivo não contém uma rede neural válida');
                end
            catch ME
                uialert(app.UIFigure, ['Erro ao carregar rede: ' ME.message], 'Erro de Carregamento');
                app.TrainButton.Enable = 'on';
            end

            drawnow;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.classes = {};
            app.categories = {};
            app.rootPath = '';
            app.net = {};
            app.bestNET1 = [];
            app.bestNET2 = [];
            app.bestNET3 = [];
            app.trainedNet = [];
            app.allResults = [];
            app.isNetworkLoaded = false;
            app.step = 1;


            app.TextArea.Value = '';
            delete(app.Tree.Children);


            app.fTreino.CheckedNodes = [app.trainlmNode];
            app.fOculta.CheckedNodes = [app.tansigNode];
            app.fSaida.CheckedNodes = [app.purelinNode_2];
            app.TopologiaEditField.Value = '10';
            app.RatiosEditField.Value = '0.7 0.15 0.15';
            app.Spinner.Value = 1;
            app.Spinner_2.Value = 1;
            app.CombinaesDropDown.Value = 'Completo';


            app.NETLoadedLabel.Visible = 'off';
            app.Label.Text = 'A utilizar pasta atual';
            app.Label.FontColor = [0 0.7 0];
            app.Label_2.Text = '';


            defaultCategories = {'train', 'start', 'test'};
            for cat = defaultCategories
                app.categories{end+1} = cat{1};
                node = uitreenode(app.Tree, 'Text', cat{1}, 'NodeData', 'category');
                app.Tree.CheckedNodes = [app.Tree.CheckedNodes; node];
            end


            defaultClasses = {'kite', 'triangle', 'square', 'trapezoid', 'parallelogram', 'circle'};
            for cls = defaultClasses
                app.classes{end+1} = cls{1};

                for categoryNode = app.Tree.Children'
                    uitreenode(categoryNode, 'Text', cls{1}, 'NodeData', 'class');
                end
            end


            updateSpinner(app);
            app.TrainButton.Enable = 'on';


            app.TopologiaEditField.BackgroundColor = [1 1 1];
            app.RatiosEditField.BackgroundColor = [1 1 1];
            app.fTreino.BackgroundColor = [1 1 1];
            app.fOculta.BackgroundColor = [1 1 1];
            app.fSaida.BackgroundColor = [1 1 1];

            CleardrawButtonPushed(app,event);
            startupFcn(app);

            drawnow;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            clickType = app.UIFigure.SelectionType;
            if app.TabGroup2.SelectedTab ~= app.RunTab
                return;
            end

            pt = get(app.UIAxes, 'CurrentPoint');
            x = round(pt(1,1));
            y = round(pt(1,2));

            if x >= 1 && x <= 224 && y >= 1 && y <= 224
                app.isDrawing = true;

                if strcmp(clickType, 'normal')
                    app.drawDot(x, y);
                    disp(['Drawing at: x=', num2str(x), ', y=', num2str(y)]);
                elseif strcmp(clickType, 'alt')
                    app.eraseDot(x, y);
                    disp(['Erasing at: x=', num2str(x), ', y=', num2str(y)]);
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion2(app, event)
            if app.isDrawing
                pt = get(app.UIAxes, 'CurrentPoint');
                x = round(pt(1,1));
                y = round(pt(1,2));

                if x >= 1 && x <= 224 && y >= 1 && y <= 224
                    clickType = app.UIFigure.SelectionType;

                    if strcmp(clickType, 'normal')
                        app.drawDot(x, y);
                    elseif strcmp(clickType, 'alt')
                        app.eraseDot(x, y);
                    end
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp2(app, event)
            app.isDrawing = false;
        end

        % Button pushed function: CleardrawButton
        function CleardrawButtonPushed(app, event)
            app.currentImage = ones(224, 224, 'uint8') * 255;
            set(app.imageHandle, 'CData', app.currentImage);
            drawnow;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Imagens (*.png, *.jpg)'}, ...
                'Selecionar uma imagem');

            if isequal(file, 0)
                disp('Carregamento cancelado');
                return;
            end

            fullPath = fullfile(path, file);

            img = imread(fullPath);
            img = im2gray(img);
            img = imresize(img, [224 224]);

            if islogical(img)
                img = uint8(~img) * 255;
            elseif isfloat(img)
                img = uint8(255 * img);
            end

            app.currentImage = img;

            set(app.imageHandle, 'CData', app.currentImage);
            drawnow;

            disp(['Imagem carregada: ', fullPath]);
        end

        % Button pushed function: TestImageButton
        function TestImageButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value; newline 'Testando imagem desenhada ou carregada...'];
            drawnow;
            scroll(app.TextArea, "bottom");

            if isempty(app.allResults) && ~app.isNetworkLoaded
                uialert(app.UIFigure, 'Nenhuma rede treinada disponível. Treine ou carregue uma rede primeiro.', ...
                    'Erro de Teste', 'Icon', 'error');
                return;
            end
            app.TabGroup2.SelectedTab = app.ResultsTab;

            data = app.carregarImagem();


            if app.isNetworkLoaded
                networks = {app.trainedNet};
                networkNames = {'Rede Carregada'};
            elseif app.Only3bestnetCheckBox.Value
                networks = {app.bestNET1, app.bestNET2, app.bestNET3};
                networkNames = {'Melhor Rede 1', 'Melhor Rede 2', 'Melhor Rede 3'};
            else
                networks = cell(1, length(app.allResults));
                networkNames = cell(1, length(app.allResults));
                for i = 1:length(app.allResults)
                    networks{i} = app.allResults(i).best_net;
                end
            end


            value = app.DropDown2.Value;
            if iscell(value)
                value = value{1};
            end
            value = char(value);

            if iscell(app.classes)
                cIdx = find(strcmp(app.classes, char(value)), 1);
            else
                cIdx = find(strcmp(string(app.classes), value), 1);
            end


            for i = 1:length(networks)
                net = networks{i};
                if isempty(net)
                    continue;
                end

                saida = net(data);
                [~, idx] = max(saida);
                predictedClass = string(app.classes(idx));
                precisao = double(idx == cIdx) * 100;
                app.currentTestID = app.currentTestID + 1;
                app.DropDownPlot.Items{end+1} = num2str(app.currentTestID);


                if app.isNetworkLoaded
                    networkID = 'Loaded Network';
                elseif app.Only3bestnetCheckBox.Value
                    [~, netIdx] = ismember(net, {app.bestNET1, app.bestNET2, app.bestNET3});
                    networkID = sprintf('Best Network %d', netIdx);
                else
                    networkID = sprintf('%d', i);
                end


                confMat = confusionmat(cIdx, idx);
                confMatStr = evalc('disp(confMat)');


                app.testCases(end+1) = struct(...
                    'id', app.currentTestID, ...
                    'networkID', networkID, ...
                    'actual', cIdx, ...
                    'predicted', idx, ...
                    'confMatStr', confMatStr);

                app.DropDownPlot.Enable = "on";
                app.DropDownPlot.Visible = "on";
                app.plotconfusionButton.Enable = "on";
                app.plotconfusionButton.Visible= "on";

                app.DropDownPlot.Value = app.DropDownPlot.Items{end};
                drawnow;

                app.TextArea.Value = [
                    app.TextArea.Value;
                    sprintf("Configuração %d/%d:", i, length(networks));
                    sprintf("    Plot confusion #%d", app.currentTestID);
                    sprintf("    Classe real: %s", value);
                    sprintf("    Classe prevista: %s", predictedClass);
                    sprintf("    Precisão: %.2f%%", precisao);
                    ""
                    ];
                drawnow;
                scroll(app.TextArea, "bottom");
            end
            updateBestModelsTest(app);
            app.TextArea.Value{end+1} = 'Teste concluído para imagem.';
            scroll(app.TextArea, "bottom");
        end

        % Button pushed function: plotconfusionButton
        function plotconfusionButtonPushed(app, event)
            if isempty(app.DropDownPlot.Items)
                uialert(app.UIFigure, 'Nenhum caso de teste disponível', 'Error');
                return;
            end

            selectedID = str2double(app.DropDownPlot.Value);
            testIdx = find([app.testCases.id] == selectedID, 1);

            if isempty(testIdx)
                uialert(app.UIFigure, 'Seleção de caso de teste inválida', 'Error');
                return;
            end


            actual = app.testCases(testIdx).actual;
            predicted = app.testCases(testIdx).predicted;


            actualCat = categorical(actual, 1:numel(app.classes), app.classes);
            predictedCat = categorical(predicted, 1:numel(app.classes), app.classes);


            figure;
            plotconfusion(actualCat, predictedCat);
            title(sprintf('Matriz de Confusão - Caso de Teste %d', selectedID));
        end

        % Button pushed function: ExportlogsButton
        function ExportlogsButtonPushed(app, event)
            if isempty(app.TextArea.Value) || isempty(strtrim(strjoin(app.TextArea.Value, '')))
                uialert(app.UIFigure, 'Nenhumas logs para exportar!', 'Export Error', 'Icon', 'error');
                return;
            end


            [file, path] = uiputfile('*.txt', 'Save Logs As');
            if file == 0
                return;
            end


            try
                fid = fopen(fullfile(path, file), 'w');
                fprintf(fid, '%s\n', app.TextArea.Value{:});
                fclose(fid);
                uialert(app.UIFigure, 'Logs exportadas com sucesso!', 'Success', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['Exportação falhou: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ExportExcelButton
        function ExportExcelButtonPushed(app, event)
           if isempty(app.allResults) && isempty(app.testCases)
                uialert(app.UIFigure, 'Sem dados para exportar', 'Erro');
                return;
            end

            [ficheiro, caminho] = uiputfile('*.xlsx', 'Guardar Como');
            if ficheiro == 0, return; end
            caminhoCompleto = fullfile(caminho, ficheiro);

            try

                if ~isempty(app.allResults)

                    dadosTreino = cell(length(app.allResults)+1, 7);
                    dadosTreino(1,:) = {'ID da Rede', 'Topologia', 'Função Oculta', 'Função Saída',...
                        'Função Treino', 'Ratios', 'Precisão Global'};

                    for i = 1:length(app.allResults)
                        config = app.allResults(i).config;
                        dadosTreino{i+1,1} = i;
                        dadosTreino{i+1,2} = mat2str(config{1});
                        dadosTreino{i+1,3} = config{2};
                        dadosTreino{i+1,4} = config{3};
                        dadosTreino{i+1,5} = config{4};
                        dadosTreino{i+1,6} = mat2str(config{5});
                        dadosTreino{i+1,7} = app.allResults(i).avg_acc*100;
                    end


                    bestNetsData = {};
                    bestNets = {app.bestNET1, app.bestNET2, app.bestNET3};

                    for b = 1:3
                        if ~isempty(bestNets{b})
                            for r = 1:length(app.allResults)
                                if isequal(bestNets{b}, app.allResults(r).best_net)
                                    newRow = dadosTreino(r+1,:);
                                    newRow{1} = sprintf('Melhor #%d', b);
                                    bestNetsData = [bestNetsData; newRow];
                                    break;
                                end
                            end
                        end
                    end


                    if ~isempty(bestNetsData)
                        dadosTreino = [dadosTreino;
                            {'=== MELHORES REDES ===', '', '', '', '', '', ''};
                            bestNetsData];
                    end

                    writecell(dadosTreino, caminhoCompleto, 'Sheet', 'Resultados Treino');
                end


                if ~isempty(app.testCases)
                    cabecalhosTeste = cell(1, 4 + length(app.classes));
                    cabecalhosTeste(1:4) = {'ID Teste', 'Rede Usada', 'Matriz Confusão', 'Precisão de Teste'};

                    for c = 1:length(app.classes)
                        cabecalhosTeste{4+c} = app.classes{c};
                    end

                    dadosTeste = cell(length(app.testCases)+1, length(cabecalhosTeste));
                    dadosTeste(1,:) = cabecalhosTeste;


                    for i = 1:length(app.testCases)
                        testCase = app.testCases(i);

                        actual = testCase.actual;
                        predicted = testCase.predicted;

                        confMat = confusionmat(actual, predicted, 'Order', 1:length(app.classes));
                        classAcc = diag(confMat)./sum(confMat,2)*100;
                        classAcc(isnan(classAcc)) = 0;

                        dadosTeste{i+1,1} = testCase.id;
                        dadosTeste{i+1,2} = testCase.networkID;
                        dadosTeste{i+1,3} = mat2str(confMat);
                        dadosTeste{i+1,4} = mean(actual == predicted)*100;

                        for c = 1:length(app.classes)
                            if c <= length(classAcc)
                                dadosTeste{i+1,4+c} = classAcc(c);
                            else
                                dadosTeste{i+1,4+c} = 0;
                            end
                        end
                    end

                    writecell(dadosTeste, caminhoCompleto, 'Sheet', 'Resultados Teste');
                end

                msgbox('Dados exportados com sucesso!', 'Sucesso');
            catch ME
                uialert(app.UIFigure, ['Exportação falhou: ' ME.message], 'Erro');
            end
        end

        % Button pushed function: Savebest1Button
        function Savebest1ButtonPushed(app, event)
            if isempty(app.bestNET1)
                uialert(app.UIFigure, 'Nenhuma Melhor Rede #1 disponível', 'Erro');
                return;
            end
            [ficheiro, caminho] = uiputfile('*.mat', 'Guardar Melhor Rede 1', 'melhorRede1.mat');
            if ficheiro == 0
                return;
            end
            try
                net = app.bestNET1;
                save(fullfile(caminho, ficheiro), 'net', '-mat');
                msgbox('Melhor Rede #1 guardada com sucesso!', 'Sucesso');
            catch ME
                uialert(app.UIFigure, ['Falha ao guardar: ' ME.message], 'Erro');
            end
        end

        % Button pushed function: Savebest2Button
        function Savebest2ButtonPushed(app, event)
            if isempty(app.bestNET2)
                uialert(app.UIFigure, 'Nenhuma Melhor Rede #2 disponível', 'Erro');
                return;
            end
            [ficheiro, caminho] = uiputfile('*.mat', 'Guardar Melhor Rede 2', 'melhorRede2.mat');
            if ficheiro == 0
                return;
            end
            try
                net = app.bestNET2;
                save(fullfile(caminho, ficheiro), 'net', '-mat');
                msgbox('Melhor Rede #2 guardada com sucesso!', 'Sucesso');
            catch ME
                uialert(app.UIFigure, ['Falha ao guardar: ' ME.message], 'Erro');
            end
        end

        % Button pushed function: Savebest3Button
        function Savebest3ButtonPushed(app, event)
            if isempty(app.bestNET3)
                uialert(app.UIFigure, 'Nenhuma Melhor Rede #3 disponível', 'Erro');
                return;
            end
            [ficheiro, caminho] = uiputfile('*.mat', 'Guardar Melhor Rede 3 Como', 'melhorRede3.mat');
            if ficheiro == 0
                return;
            end
            try
                net = app.bestNET3;
                save(fullfile(caminho, ficheiro), 'net', '-mat');
                msgbox('Melhor Rede #3 guardada com sucesso!', 'Sucesso');
            catch ME
                uialert(app.UIFigure, ['Falha ao guardar: ' ME.message], 'Erro');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp2, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion2, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.GridLayout8 = uigridlayout(app.ParamsTab);
            app.Panel_7 = uipanel(app.GridLayout8);
            app.GridLayout3 = uigridlayout(app.Panel_7);
            app.FunesdeativaoLabel = uilabel(app.GridLayout3);
            app.FunesdeativaoLabel.Text = 'Funções de ativação';
            app.fOculta = uitree(app.GridLayout3, 'checkbox');
            app.tansigNode = uitreenode(app.fOculta);
            app.tansigNode.NodeData = 'tansig';
            app.tansigNode.Text = 'tansig';
            app.competNode = uitreenode(app.fOculta);
            app.competNode.NodeData = 'compet';
            app.competNode.Text = 'compet';
            app.hardlimNode = uitreenode(app.fOculta);
            app.hardlimNode.NodeData = 'hardlim';
            app.hardlimNode.Text = 'hardlim';
            app.hardlimsNode = uitreenode(app.fOculta);
            app.hardlimsNode.NodeData = 'hardlims';
            app.hardlimsNode.Text = 'hardlims';
            app.logsigNode = uitreenode(app.fOculta);
            app.logsigNode.NodeData = 'logsig';
            app.logsigNode.Text = 'logsig';
            app.netinvNode = uitreenode(app.fOculta);
            app.netinvNode.NodeData = 'netinv';
            app.netinvNode.Text = 'netinv';
            app.poslinNode = uitreenode(app.fOculta);
            app.poslinNode.NodeData = 'poslin';
            app.poslinNode.Text = 'poslin';
            app.purelinNode = uitreenode(app.fOculta);
            app.purelinNode.NodeData = 'purelin';
            app.purelinNode.Text = 'purelin';
            app.radbasNode = uitreenode(app.fOculta);
            app.radbasNode.NodeData = 'radbas';
            app.radbasNode.Text = 'radbas';
            app.radbasnNode = uitreenode(app.fOculta);
            app.radbasnNode.NodeData = 'radbasn';
            app.radbasnNode.Text = 'radbasn';
            app.satlinNode = uitreenode(app.fOculta);
            app.satlinNode.NodeData = 'satlin';
            app.satlinNode.Text = 'satlin';
            app.satlinsNode = uitreenode(app.fOculta);
            app.satlinsNode.NodeData = 'satlins';
            app.satlinsNode.Text = 'satlins';
            app.softmaxNode = uitreenode(app.fOculta);
            app.softmaxNode.NodeData = 'softmax';
            app.softmaxNode.Text = 'softmax';
            % Assign Checked Nodes
            app.fOculta.CheckedNodesChangedFcn = createCallbackFcn(app, @fOcultaCheckedNodesChanged, true);
            app.fSaida = uitree(app.GridLayout3, 'checkbox');
            app.purelinNode_2 = uitreenode(app.fSaida);
            app.purelinNode_2.NodeData = 'purelin';
            app.purelinNode_2.Text = 'purelin';
            app.competNode_2 = uitreenode(app.fSaida);
            app.competNode_2.NodeData = 'compet';
            app.competNode_2.Text = 'compet';
            app.hardlimNode_2 = uitreenode(app.fSaida);
            app.hardlimNode_2.NodeData = 'hardlim';
            app.hardlimNode_2.Text = 'hardlim';
            app.hardlimsNode_2 = uitreenode(app.fSaida);
            app.hardlimsNode_2.NodeData = 'hardlims';
            app.hardlimsNode_2.Text = 'hardlims';
            app.logsigNode_2 = uitreenode(app.fSaida);
            app.logsigNode_2.NodeData = 'logsig';
            app.logsigNode_2.Text = 'logsig';
            app.netinvNode_2 = uitreenode(app.fSaida);
            app.netinvNode_2.NodeData = 'netinv';
            app.netinvNode_2.Text = 'netinv';
            app.poslinNode_2 = uitreenode(app.fSaida);
            app.poslinNode_2.NodeData = 'poslin';
            app.poslinNode_2.Text = 'poslin';
            app.radbasNode_2 = uitreenode(app.fSaida);
            app.radbasNode_2.NodeData = 'radbas';
            app.radbasNode_2.Text = 'radbas';
            app.radbasnNode_2 = uitreenode(app.fSaida);
            app.radbasnNode_2.NodeData = 'radbasn';
            app.radbasnNode_2.Text = 'radbasn';
            app.satlinNode_2 = uitreenode(app.fSaida);
            app.satlinNode_2.NodeData = 'satlin';
            app.satlinNode_2.Text = 'satlin';
            app.satlinsNode_2 = uitreenode(app.fSaida);
            app.satlinsNode_2.NodeData = 'satlins';
            app.satlinsNode_2.Text = 'satlins';
            app.softmaxNode_2 = uitreenode(app.fSaida);
            app.softmaxNode_2.NodeData = 'softmax';
            app.softmaxNode_2.Text = 'softmax';
            app.tansigNode_2 = uitreenode(app.fSaida);
            app.tansigNode_2.NodeData = 'tansig';
            app.tansigNode_2.Text = 'tansig';
            % Assign Checked Nodes
            app.fSaida.CheckedNodesChangedFcn = createCallbackFcn(app, @fSaidaCheckedNodesChanged, true);
            app.OcultaPanel = uipanel(app.GridLayout3);
            app.OcultaPanel.Title = 'Oculta';
            app.SadaPanel = uipanel(app.GridLayout3);
            app.SadaPanel.Title = 'Saída';
            app.RatiosEditField = uieditfield(app.GridLayout8, 'text');
            app.RatiosEditField.ValueChangedFcn = createCallbackFcn(app, @RatiosEditFieldValueChanged, true);
            app.RatiosEditField.ValueChangingFcn = createCallbackFcn(app, @RatiosEditFieldValueChanging, true);
            app.RatiosEditField.Placeholder = 'Example: 0.6 0.2 0.2, 1 0 0, 0.7 0.15 0.15';
            app.RatiosEditField.Value = '0.7 0.15 0.15';
            app.TopologiaEditField = uieditfield(app.GridLayout8, 'text');
            app.TopologiaEditField.ValueChangedFcn = createCallbackFcn(app, @TopologiaEditFieldValueChanged, true);
            app.TopologiaEditField.ValueChangingFcn = createCallbackFcn(app, @TopologiaEditFieldValueChanging, true);
            app.TopologiaEditField.Placeholder = 'Example: 10, 10 10, 20 10';
            app.TopologiaEditField.Value = '10';
            app.RatiosLabel = uilabel(app.GridLayout8);
            app.RatiosLabel.Text = 'Ratios';
            app.TopologiaLabel = uilabel(app.GridLayout8);
            app.TopologiaLabel.Text = 'Topologia';
            app.Panel_8 = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel_8);
            app.fTreino = uitree(app.GridLayout9, 'checkbox');
            app.trainlmNode = uitreenode(app.fTreino);
            app.trainlmNode.NodeData = 'trainlm';
            app.trainlmNode.Text = 'trainlm';
            app.trainbfgNode = uitreenode(app.fTreino);
            app.trainbfgNode.NodeData = 'trainbfg';
            app.trainbfgNode.Text = 'trainbfg';
            app.trainbrNode = uitreenode(app.fTreino);
            app.trainbrNode.NodeData = 'trainbr';
            app.trainbrNode.Text = 'trainbr';
            app.traincNode = uitreenode(app.fTreino);
            app.traincNode.NodeData = 'trainc';
            app.traincNode.Text = 'trainc';
            app.traincgbNode = uitreenode(app.fTreino);
            app.traincgbNode.NodeData = 'traincgb';
            app.traincgbNode.Text = 'traincgb';
            app.traincgfNode = uitreenode(app.fTreino);
            app.traincgfNode.NodeData = 'traincgf';
            app.traincgfNode.Text = 'traincgf';
            app.traincgpNode = uitreenode(app.fTreino);
            app.traincgpNode.NodeData = 'traincgp';
            app.traincgpNode.Text = 'traincgp';
            app.traingdNode = uitreenode(app.fTreino);
            app.traingdNode.NodeData = 'traingd';
            app.traingdNode.Text = 'traingd';
            app.traingdaNode = uitreenode(app.fTreino);
            app.traingdaNode.NodeData = 'traingda';
            app.traingdaNode.Text = 'traingda';
            app.traingdmNode = uitreenode(app.fTreino);
            app.traingdmNode.NodeData = 'traingdm';
            app.traingdmNode.Text = 'traingdm';
            app.traingdxNode = uitreenode(app.fTreino);
            app.traingdxNode.NodeData = 'traingdx';
            app.traingdxNode.Text = 'traingdx';
            app.trainossNode = uitreenode(app.fTreino);
            app.trainossNode.NodeData = 'trainoss';
            app.trainossNode.Text = 'trainoss';
            app.trainrNode = uitreenode(app.fTreino);
            app.trainrNode.NodeData = 'trainr';
            app.trainrNode.Text = 'trainr';
            app.trainrpNode = uitreenode(app.fTreino);
            app.trainrpNode.NodeData = 'trainrp';
            app.trainrpNode.Text = 'trainrp';
            app.trainsNode = uitreenode(app.fTreino);
            app.trainsNode.NodeData = 'trains';
            app.trainsNode.Text = 'trains';
            app.trainscgNode = uitreenode(app.fTreino);
            app.trainscgNode.NodeData = 'trainscg';
            app.trainscgNode.Text = 'trainscg';
            % Assign Checked Nodes
            app.fTreino.CheckedNodesChangedFcn = createCallbackFcn(app, @fTreinoCheckedNodesChanged, true);
            app.FunesdetreinoLabel = uilabel(app.GridLayout9);
            app.FunesdetreinoLabel.Text = 'Funções de treino';
            app.Panel_10 = uipanel(app.GridLayout8);
            app.GridLayout11 = uigridlayout(app.Panel_10);
            app.Spinner_2 = uispinner(app.GridLayout11);
            app.Spinner_2.Limits = [1 Inf];
            app.Spinner_2.ValueDisplayFormat = '%.0f';
            app.Spinner_2.Value = 1;
            app.RepetiesLabel = uilabel(app.GridLayout11);
            app.RepetiesLabel.Text = 'Repetições';
            app.Panel_9 = uipanel(app.GridLayout8);
            app.GridLayout10 = uigridlayout(app.Panel_9);
            app.CombinaesDropDownLabel = uilabel(app.GridLayout10);
            app.CombinaesDropDownLabel.Text = 'Combinações';
            app.CombinaesDropDown = uidropdown(app.GridLayout10);
            app.CombinaesDropDown.Items = {'Otimizado', 'Específico', 'Completo'};
            app.CombinaesDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinaesDropDownValueChanged, true);
            app.CombinaesDropDown.Value = 'Completo';
            app.Spinner = uispinner(app.GridLayout10);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Enable = 'off';
            app.Spinner.Visible = 'off';
            app.Spinner.Tooltip = {''};
            app.Spinner.Value = 1;
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.Panel_5 = uipanel(app.DatasetTab);
            app.Panel_5.Enable = 'off';
            app.Panel_5.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.ConfirmarButton = uibutton(app.GridLayout5, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.RootEditFieldLabel = uilabel(app.GridLayout5);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.GridLayout5, 'text');
            app.RootEditField.ValueChangedFcn = createCallbackFcn(app, @RootEditFieldValueChanged, true);
            app.RootEditField.ValueChangingFcn = createCallbackFcn(app, @RootEditFieldValueChanging, true);
            app.Panel_4 = uipanel(app.DatasetTab);
            app.AdicionarpastaButton = uibutton(app.Panel_4, 'push');
            app.AdicionarpastaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarpastaButtonPushed, true);
            app.AdicionarpastaButton.Enable = 'off';
            app.AdicionarpastaButton.Text = 'Adicionar pasta';
            app.EditField = uieditfield(app.Panel_4, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.RemoverpastaButton = uibutton(app.Panel_4, 'push');
            app.RemoverpastaButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverpastaButtonPushed, true);
            app.RemoverpastaButton.Enable = 'off';
            app.RemoverpastaButton.Text = 'Remover pasta';
            app.RemoverclasseButton = uibutton(app.Panel_4, 'push');
            app.RemoverclasseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverclasseButtonPushed, true);
            app.RemoverclasseButton.Enable = 'off';
            app.RemoverclasseButton.Text = 'Remover classe';
            app.AdicionarclasseButton = uibutton(app.Panel_4, 'push');
            app.AdicionarclasseButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarclasseButtonPushed, true);
            app.AdicionarclasseButton.Enable = 'off';
            app.AdicionarclasseButton.Text = 'Adicionar classe';
            app.Tree = uitree(app.Panel_4, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.GridLayout7 = uigridlayout(app.RunTab);
            app.UIAxes = uiaxes(app.GridLayout7);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = 0;
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = 0;
            app.UIAxes.ZTick = 0;
            app.UIAxes.ZTickLabel = {'0'};
            app.TrainButton = uibutton(app.GridLayout7, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.GridLayout7, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Only3bestnetCheckBox = uicheckbox(app.GridLayout7);
            app.Only3bestnetCheckBox.Text = 'Only 3 best net';
            app.LoadNETButton = uibutton(app.GridLayout7, 'push');
            app.LoadNETButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNETButtonPushed, true);
            app.LoadNETButton.Text = 'Load NET';
            app.NETLoadedLabel = uilabel(app.GridLayout7);
            app.NETLoadedLabel.Visible = 'off';
            app.NETLoadedLabel.Text = 'NET Loaded';
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadImageButton = uibutton(app.GridLayout7, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CleardrawButton = uibutton(app.GridLayout7, 'push');
            app.CleardrawButton.ButtonPushedFcn = createCallbackFcn(app, @CleardrawButtonPushed, true);
            app.CleardrawButton.Text = 'Clear draw';
            app.TestImageButton = uibutton(app.GridLayout7, 'push');
            app.TestImageButton.ButtonPushedFcn = createCallbackFcn(app, @TestImageButtonPushed, true);
            app.TestImageButton.Text = 'Test Image';
            app.DropDown2 = uidropdown(app.GridLayout7);
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.GridLayout12 = uigridlayout(app.ResultsTab);
            app.TextArea = uitextarea(app.GridLayout12);
            app.DropDownPlot = uidropdown(app.GridLayout12);
            app.plotconfusionButton = uibutton(app.GridLayout12, 'push');
            app.plotconfusionButton.ButtonPushedFcn = createCallbackFcn(app, @plotconfusionButtonPushed, true);
            app.plotconfusionButton.Text = 'plotconfusion';
            app.Savebest1Button = uibutton(app.GridLayout12, 'push');
            app.Savebest1Button.ButtonPushedFcn = createCallbackFcn(app, @Savebest1ButtonPushed, true);
            app.Savebest1Button.Enable = 'off';
            app.Savebest1Button.Text = 'Save best#1';
            app.Savebest2Button = uibutton(app.GridLayout12, 'push');
            app.Savebest2Button.ButtonPushedFcn = createCallbackFcn(app, @Savebest2ButtonPushed, true);
            app.Savebest2Button.Enable = 'off';
            app.Savebest2Button.Text = 'Save best#2';
            app.Savebest3Button = uibutton(app.GridLayout12, 'push');
            app.Savebest3Button.ButtonPushedFcn = createCallbackFcn(app, @Savebest3ButtonPushed, true);
            app.Savebest3Button.Enable = 'off';
            app.Savebest3Button.Text = 'Save best#3';
            app.ExportlogsButton = uibutton(app.GridLayout12, 'push');
            app.ExportlogsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlogsButtonPushed, true);
            app.ExportlogsButton.Text = 'Export logs';
            app.ExportExcelButton = uibutton(app.GridLayout12, 'push');
            app.ExportExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportExcelButtonPushed, true);
            app.ExportExcelButton.Text = 'Export Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = e_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end